classdef Assignment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        MainTab                matlab.ui.container.Tab
        GameTitle              matlab.ui.control.Label
        GameTitleShadow        matlab.ui.control.Label
        Creator                matlab.ui.control.Label
        ExitButton             matlab.ui.control.Button
        HighScoresButton       matlab.ui.control.Button
        PlayButton             matlab.ui.control.Button
        bg_main                matlab.ui.control.Image
        DifficultyTab          matlab.ui.container.Tab
        DifficultyTitle        matlab.ui.control.Label
        DifficultyTitleShadow  matlab.ui.control.Label
        DifficultyBtnEasy      matlab.ui.control.Button
        DifficultyBtnNormal    matlab.ui.control.Button
        DifficultyBtnHard      matlab.ui.control.Button
        DifficultyBtnBack      matlab.ui.control.Button
        bg_difficulty          matlab.ui.control.Image
        GameTab                matlab.ui.container.Tab
        img_wind_direction     matlab.ui.control.Image
        Instructions           matlab.ui.control.Label
        WindValue              matlab.ui.control.Label
        WindLabel              matlab.ui.control.Label
        vector                 matlab.ui.control.Image
        snowball               matlab.ui.control.Image
        img_lives1             matlab.ui.control.Image
        img_lives2             matlab.ui.control.Image
        img_lives3             matlab.ui.control.Image
        img_lives4             matlab.ui.control.Image
        img_lives5             matlab.ui.control.Image
        LivesLabel             matlab.ui.control.Label
        ScoreLabelGame         matlab.ui.control.Label
        ScoreValueGame         matlab.ui.control.Label
        NextScoreLabel         matlab.ui.control.Label
        NextScoreValue         matlab.ui.control.Label
        CurrentPosLabel        matlab.ui.control.Label
        CurrentPosValue        matlab.ui.control.Label
        Slider                 matlab.ui.control.Slider
        PlayButtonGame         matlab.ui.control.Button
        ShootingAngle          matlab.ui.control.NinetyDegreeGauge
        QuitButton             matlab.ui.control.Button
        bg_game                matlab.ui.control.Image
        UIAxes                 matlab.ui.control.UIAxes
        GameOverTab            matlab.ui.container.Tab
        NameLabel              matlab.ui.control.Label
        ScoreLabelScore        matlab.ui.control.Label
        ScoreValueScore        matlab.ui.control.Label
        NameField              matlab.ui.control.EditField
        DoneButtonScore        matlab.ui.control.Button
        bg_gameover            matlab.ui.control.Image
        HighScoresTab          matlab.ui.container.Tab
        HighScoreTitle         matlab.ui.control.Label
        HighScoreTitleShadow   matlab.ui.control.Label
        ScoreBtnEasy           matlab.ui.control.Button
        ScoreBtnNormal         matlab.ui.control.Button
        ScoreBtnHard           matlab.ui.control.Button
        FirstName              matlab.ui.control.Label
        FirstScore             matlab.ui.control.Label
        SecondScore            matlab.ui.control.Label
        SecondName             matlab.ui.control.Label
        ThirdName              matlab.ui.control.Label
        ThirdScore             matlab.ui.control.Label
        FourthName             matlab.ui.control.Label
        FourthScore            matlab.ui.control.Label
        FifthPos               matlab.ui.control.Label
        FifthName              matlab.ui.control.Label
        FifthScore             matlab.ui.control.Label
        SixthPos               matlab.ui.control.Label
        SixthName              matlab.ui.control.Label
        SixthScore             matlab.ui.control.Label
        king_bob               matlab.ui.control.Image
        stuart                 matlab.ui.control.Image
        kevin                  matlab.ui.control.Image
        FourthPos              matlab.ui.control.Label
        podium                 matlab.ui.control.Image
        SixthPosImg            matlab.ui.control.Image
        FifthPosImg            matlab.ui.control.Image
        FourthPosImg           matlab.ui.control.Image
        BackButtonHighScore    matlab.ui.control.Button
        bg_highscores          matlab.ui.control.Image
    end

    
    properties (Access = private)
        PlayerName = "FAW"              % Stores Plyaername for highscore, if not provided by user, then keep default value
        Difficulty
        Score = 0
        HighScores 
        HighScorers
        HighScoreMode = 2               % Set Normal mode as default high score mode.
        Filename = 'HighScores.txt';
        AngleSet = 0
        SpeedSet = 0
        Angle = 0
        GoalRange
        GoalBars
        Va  
        Position_x
        Position_y
        g = 9.81
        Lives = 5
        
    end
    
    methods (Access = private)

        function newGame(app)
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            
            app.PlayerName = "FAW";
            app.Lives = 5;
            app.Score = 0;
            app.AngleSet = 0;
            app.SpeedSet = 0;
            app.ShootingAngle.Value = app.ShootingAngle.Limits(1);
            app.Slider.Value = app.Slider.Limits(1);
            app.ScoreValueGame.Text = "0";
            updateNextScore(app);
            app.img_lives1.Visible = 'on';
            app.img_lives2.Visible = 'on';
            app.img_lives3.Visible = 'on';
            app.img_lives4.Visible = 'on';
            app.img_lives5.Visible = 'on';
            
        end

        function mainGame(app)

            try
                while (app.Lives > 0)                       % 1 loop represents 1 shot
                    app.snowball.Position = [8 8 25 25];    % Sets the position of the projectile in the window
                    app.Va = setWindVelocity(app);          % Sets wind velocity
                    app.AngleSet = 0;                       % 0 => Angle is not set yet
                    app.SpeedSet = 0;                       % 0 => Speed is not set yet
                    setTarget(app);                         % Sets the position of the target
                     
                    setAngle(app);                          % Moves the Angle gauge up and down until Play button is clicked
                    setSpeed(app);                          % Moves the Speed slider up and down until Play button is clicked
                    calculate(app);                         % Calculates the x and y coordinates of the projectile
                    shoot(app);                             % Shoots the projectile
                    scoring(app);                           % Calculates the score after each shot
                    updateNextScore(app);                   % Updates Next High Score & Current Position in the Game window
                    pause(1);
                end
            
            
            app.TabGroup.SelectedTab = app.GameOverTab;
            app.ScoreValueScore.Text = num2str(app.Score);  % Update the score in the game over window

            catch
            end
        end
        
        function readAllHighScores(app)
            try
                file = fopen(app.Filename);
                data = textscan(file,'%s');
                fclose(file);
                data = string(data{:});                                             % Reads the whole file into one string
                app.HighScorers = transpose(reshape(data(1:18), 6, 3));             % first 18 elements converted to a 6x3 matrix
                app.HighScores = transpose(str2double(reshape(data(19:36), 6, 3))); % last 18 elements converted to another 6x3 matrix
            catch 
                %If file doesnt exist, or something went wrong, set default high scores
                app.HighScorers = [ "EAA" "EBB" "ECC" "EDD" "EEE" "EFF";
                                    "NAA" "NBB" "NCC" "NDD" "NEE" "NFF"; 
                                    "HAA" "HBB" "HCC" "HDD" "HEE" "HFF"];
                app.HighScores = [49 39 29 19 9 3; 48 38 28 18 8 1; 47 37 27 17 7 2];
            end
        end
        
        
        function Va = setWindVelocity(app)
            if (app.Difficulty ~= 3)        % wind velocity = 0 for easy and normal mode
                    Va = 0;
            else
                Va_up = 0.25;   % max wind velocity
                Va_low = 0.1;   % min wind velocity

                % set a random value between 0.1 and 0.25
                Va = rand();
                if (Va > Va_up)
                    Va = mod(Va, Va_up);
                end
                if (Va < Va_low)
                    Va = Va + Va_low;
                end
            end

            app.WindValue.Text = num2str(round(Va, 2));

            
        end

        function setTarget(app)
            % Graph is 8 unit tall
            % Target need to between 1 and 7 with 1 unit to spare from top and bottom
            % Position on the x-axis will be fixed, 19.75
            if (app.Difficulty == 1)
                app.GoalRange = [4 6];      % Target is fixed in the middle for easy mode
            else
                app.GoalRange(1) = round(1 + (7-1) .* rand(1)); % For normal and hard, low point of target is random between 1 and 6
                app.GoalRange(2) = app.GoalRange(1) + 2;        % high point of target is 2 unit above low point
            end
            try
                delete(app.GoalBars);   %B efore changing new position of the target, try to delete old one if exists
            catch
            end
            app.vector.Position = [1720 20+95*(app.GoalRange(1)) 145 194];  % Position of the image target
            %Position of the target in the graph
            app.GoalBars(1) = rectangle(app.UIAxes, 'Position',[19.75,app.GoalRange(2),0.75,10-app.GoalRange(2)],'FaceColor',[0 .5 .5]);
            app.GoalBars(2) = rectangle(app.UIAxes, 'Position',[19.75,0,0.75,app.GoalRange(1)],'FaceColor',[0 .5 .5]);
        end
        
        function setHighScores(app)
            % Set High Scorers Name
            app.FirstName.Text = app.HighScorers(app.HighScoreMode, 1);
            app.SecondName.Text = app.HighScorers(app.HighScoreMode, 2);
            app.ThirdName.Text = app.HighScorers(app.HighScoreMode, 3);
            app.FourthName.Text = app.HighScorers(app.HighScoreMode, 4);
            app.FifthName.Text = app.HighScorers(app.HighScoreMode, 5);
            app.SixthName.Text = app.HighScorers(app.HighScoreMode, 6);
            
            % Set High Scores
            app.FirstScore.Text = num2str(app.HighScores(app.HighScoreMode, 1));
            app.SecondScore.Text = num2str(app.HighScores(app.HighScoreMode, 2));
            app.ThirdScore.Text = num2str(app.HighScores(app.HighScoreMode, 3));
            app.FourthScore.Text = num2str(app.HighScores(app.HighScoreMode, 4));
            app.FifthScore.Text = num2str(app.HighScores(app.HighScoreMode, 5));
            app.SixthScore.Text = num2str(app.HighScores(app.HighScoreMode, 6));

            % Disable the button for selected mode
            if (app.HighScoreMode == 1)
                set(app.ScoreBtnEasy,'Enable','off');
                set(app.ScoreBtnNormal,'Enable','on');
                set(app.ScoreBtnHard,'Enable','on');
            elseif (app.HighScoreMode == 2)
                set(app.ScoreBtnEasy,'Enable','on');
                set(app.ScoreBtnNormal,'Enable','off');
                set(app.ScoreBtnHard,'Enable','on');
            else
                set(app.ScoreBtnEasy,'Enable','on');
                set(app.ScoreBtnNormal,'Enable','on');
                set(app.ScoreBtnHard,'Enable','off');
            end
        end


        function setAngle(app)
            app.Instructions.Text = "Click on Play button to set the angle";
            % When up = 1, go from 0 to 90, 
            % When up = 0, go from 90 to 0
            up = 1; 
            while (app.AngleSet == 0)
                if (up == 1 && app.ShootingAngle.Value == 89)
                    up = 0;
                end
    
                if (up == 0 && app.ShootingAngle.Value == 1)
                    up = 1;
                end
    
                if (up == 1)
                    app.ShootingAngle.Value = app.ShootingAngle.Value+1;
                else
                    app.ShootingAngle.Value = app.ShootingAngle.Value-1;
                end
                pause(0.01);    % Pause to nicely animate it
            end
        end
        
        function setSpeed(app)
            app.Instructions.Text = "Click on Play button to set the speed";
            % When up = 1, go from low to high, 
            % When up = 0, go from high to low
            up = 1;
            while (app.SpeedSet == 0)
                if (up == 1 && app.Slider.Value == app.Slider.Limits(2))
                    up = 0;
                end
    
                if (up == 0 && app.Slider.Value == app.Slider.Limits(1))
                    up = 1;
                end

                if (up == 1 && app.Slider.Value+0.125 <= app.Slider.Limits(2))
                    app.Slider.Value = app.Slider.Value+0.125;
                elseif (up == 0 && app.Slider.Value-0.125 >= app.Slider.Limits(1))
                    app.Slider.Value = app.Slider.Value-0.125;
                end
                pause(0.01);    % Pause to nicely animate it
            end
        end

        function calculate(app)
            
            u = app.Slider.Value;           % Get speed from speed slider
            theta = app.ShootingAngle.Value;% Get angle from angle gauge
            
            app.Position_x = linspace(0, 20, 100);  % Get 100 points from 0 to 20
            % Calculate y-coords using projectile motion formula, considering wind velocity
            app.Position_y = ((u.*app.Position_x.*sind(theta))./(u.*cosd(theta) + app.Va)) - ((0.5).*app.g.*(app.Position_x.^2))./((u.*cosd(theta)+app.Va).^2);
        end

        function shoot(app)
            app.Instructions.Text = "";             % Hide instruction message during shooting animation
            set(app.PlayButtonGame,'Enable','off'); % Disable Play button during shooting
            
            % Animate the projectile
            for i = 1:length(app.Position_x)
                plot(app.UIAxes, app.Position_x(i), app.Position_y(i), 'c*');
                app.snowball.Position = [(8+app.Position_x(i)*1793/20) (8+app.Position_y(i)*(952)/10) 25 25];
                pause(0.005)
            end
            
            set(app.PlayButtonGame,'Enable','on')   % Enable Play button after projectile was shot
        end

        function scoring(app)
            % If the y coordinate in between the target range
            % When x coordinate is 20, it considered as target hit
            % Give a small room for error margin, 0.1 is good enough iguess
            if (app.Position_y(100)+0.1 > app.GoalRange(1) && app.Position_y(100)-0.1 < app.GoalRange(2))
                app.Score = app.Score + 10;
                app.ScoreValueGame.Text = num2str(app.Score);
            else
                % If target is missed, remove one live at a time by hiding the images
                app.Lives = app.Lives - 1;
                if(app.Lives == 4)
                    app.img_lives5.Visible = 'off';
                elseif(app.Lives == 3)
                    app.img_lives4.Visible = 'off';
                elseif(app.Lives == 2)
                    app.img_lives3.Visible = 'off';
                elseif(app.Lives == 1)
                    app.img_lives2.Visible = 'off';
                else
                    app.img_lives2.Visible = 'off';
                end
            end
            
        end
        
        function updateNextScore(app)
            % Find the next high score index
            row = app.Difficulty;
            index = find(app.Score > app.HighScores(row, 1:6), 1); 
            
            % Update the next score in the game
            % If the Score is the highest, show it.
            if(app.Score == 0 || isempty(index))
                app.NextScoreValue.Text = num2str(app.HighScores(row, 6));
            elseif(index == 1)
                app.NextScoreValue.Text = num2str(app.Score);
            else
                app.NextScoreValue.Text = num2str(app.HighScores(row, index-1));

            end
            
            % Update the current position in the game window
            if(index == 1)
                app.CurrentPosValue.Text = "1st";
            elseif(index == 2)
                app.CurrentPosValue.Text = "2nd";
            elseif(index == 3)
                app.CurrentPosValue.Text = "3rd";
            elseif(index == 4)
                app.CurrentPosValue.Text = "4th";
            elseif(index == 5)
                app.CurrentPosValue.Text = "5th";
            elseif(index == 6)
                app.CurrentPosValue.Text = "6th";
            else
                app.CurrentPosValue.Text = "7th";
            end
        end
        
        function updateHighScores(app)
            
            row = app.Difficulty;
            index = find(app.Score > app.HighScores(row, 1:6), 1); %In case of same score, old score will be at the top.

            if(index)
                app.HighScores(row, 1:6) = [app.HighScores(row, 1:index-1) app.Score app.HighScores(row, index:5)];
                app.HighScorers(row, 1:6) = [app.HighScorers(row, 1:index-1) app.PlayerName app.HighScorers(row, index:5)];
    
                % Update the file
                file = fopen(app.Filename, "w");
                for row = 1:3
                    fprintf(file, "%s\t", app.HighScorers(row, 1:6));
                    fprintf(file, "%s\n", "");
                end
                for row = 1:3
                    fprintf(file, "%d\t", app.HighScores(row, 1:6));
                    fprintf(file, "%s\n", "");
                end
                fclose(file);
            end
        end
        
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            readAllHighScores(app);
            
        end

        % Button pushed function: PlayButton
        function PlayBtnMainPushed(app, event)
            app.TabGroup.SelectedTab = app.DifficultyTab;
            
        end

        % Button pushed function: HighScoresButton
        function HighScoresBtnPushed(app, event)
            app.HighScoreMode = 2;  % Represents Normal mode
            app.TabGroup.SelectedTab = app.HighScoresTab;
            
            setHighScores(app);
        end

        % Button pushed function: ExitButton
        function ExitBtnPushed(app, event)
            close all force;
            delete(app);
        end

        % Button pushed function: DifficultyBtnEasy
        function DifficultyBtnEasyPushed(app, event)
            app.Difficulty = 1;                 % 1 represents easy mode
            app.TabGroup.SelectedTab = app.GameTab;
            newGame(app);                       % Starts a new game by resetting all var
            mainGame(app);                      % Runs the main game using while loop
        end

        % Button pushed function: DifficultyBtnNormal
        function DifficultyBtnNormalPushed(app, event)
            app.Difficulty = 2;                 % 2 represents normal mode
            app.TabGroup.SelectedTab = app.GameTab;
            newGame(app);                       % Starts a new game by resetting all var
            mainGame(app);                      % Runs the main game using while loop
        end

        % Button pushed function: DifficultyBtnHard
        function DifficultyBtnHardPushed(app, event)
            app.Difficulty = 3;                 % 3 represents hard mode
            app.TabGroup.SelectedTab = app.GameTab;
            newGame(app);                       % Starts a new game by resetting all var
            mainGame(app);                      % Runs the main game using while loop
        end

        % Button pushed function: DifficultyBtnBack
        function BackBtnDifficultyPushed(app, event)
            app.TabGroup.SelectedTab = app.MainTab;
            
        end

        % Button pushed function: PlayButtonGame
        function PlayBtnGamePushed(app, event)
            if (app.AngleSet == 0)
                app.AngleSet = 1;       % 1 represents true, meaning angle has been set
            elseif (app.SpeedSet == 0)
                app.SpeedSet = 1;       % 1 represents true, meaning speed has been set
            end
        end

        % Button pushed function: QuitButton
        function QuitBtnPushed(app, event)
            app.TabGroup.SelectedTab = app.GameOverTab;
            app.ScoreValueScore.Text = num2str(app.Score);
        end

        % Value changed function: NameField
        function NameFieldValueChanged(app, event)
            value = app.NameField.Value;
            % Max length of the name is three characters
            if (strlength(value) > 3)
                app.NameField.Value = value(1:3);
            end
        end

        % Button pushed function: DoneButtonScore
        function DoneBtnGameOverPushed(app, event)
            % Update PlayerName if they have provided a name, 
            % if not keep default, which is FAW
            if (~isempty(app.NameField.Value))
                app.PlayerName = app.NameField.Value;  
            end
            
            % Update high scores
            app.HighScoreMode = app.Difficulty;
            updateHighScores(app);
            
            % Set to show the high scores in the High Scores window
            app.TabGroup.SelectedTab = app.HighScoresTab;
            setHighScores(app);
        end

        % Button pushed function: ScoreBtnEasy
        function ScoreBtnEasyPushed(app, event)
            app.HighScoreMode = 1;
            setHighScores(app);
        end

        % Button pushed function: ScoreBtnNormal
        function ScoreBtnNormalPushed(app, event)
            app.HighScoreMode = 2;
            setHighScores(app);
        end

        % Button pushed function: ScoreBtnHard
        function ScoreBtnHardPushed(app, event)
            app.HighScoreMode = 3;
            setHighScores(app);
        end

        % Button pushed function: BackButtonHighScore
        function BackBtnHighScorePushed(app, event)
            app.TabGroup.SelectedTab = app.MainTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.bg_main = uiimage(app.MainTab);
            app.bg_main.ImageSource = fullfile(pathToMLAPP, 'images', 'bg_main2.jpg');
            app.PlayButton = uibutton(app.MainTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayBtnMainPushed, true);
            app.PlayButton.Text = 'Play';
            app.HighScoresButton = uibutton(app.MainTab, 'push');
            app.HighScoresButton.ButtonPushedFcn = createCallbackFcn(app, @HighScoresBtnPushed, true);
            app.HighScoresButton.Text = 'High Scores';
            app.ExitButton = uibutton(app.MainTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitBtnPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Creator = uilabel(app.MainTab);
            app.Creator.Text = 'by Fawzan';
            app.GameTitleShadow = uilabel(app.MainTab);
            app.GameTitleShadow.Text = 'Minion Shooter';
            app.GameTitle = uilabel(app.MainTab);
            app.GameTitle.Text = 'Minion Shooter';
            app.DifficultyTab = uitab(app.TabGroup);
            app.DifficultyTab.Title = 'Difficulty';
            app.bg_difficulty = uiimage(app.DifficultyTab);
            app.bg_difficulty.ImageSource = fullfile(pathToMLAPP, 'images', 'bg_main2.jpg');
            app.DifficultyBtnBack = uibutton(app.DifficultyTab, 'push');
            app.DifficultyBtnBack.ButtonPushedFcn = createCallbackFcn(app, @BackBtnDifficultyPushed, true);
            app.DifficultyBtnBack.Text = 'Back';
            app.DifficultyBtnHard = uibutton(app.DifficultyTab, 'push');
            app.DifficultyBtnHard.ButtonPushedFcn = createCallbackFcn(app, @DifficultyBtnHardPushed, true);
            app.DifficultyBtnHard.Tooltip = {'Random Target with Random velocity left to right'};
            app.DifficultyBtnHard.Text = 'Hard';
            app.DifficultyBtnNormal = uibutton(app.DifficultyTab, 'push');
            app.DifficultyBtnNormal.ButtonPushedFcn = createCallbackFcn(app, @DifficultyBtnNormalPushed, true);
            app.DifficultyBtnNormal.Tooltip = {'Random Target'};
            app.DifficultyBtnNormal.Text = 'Normal';
            app.DifficultyBtnEasy = uibutton(app.DifficultyTab, 'push');
            app.DifficultyBtnEasy.ButtonPushedFcn = createCallbackFcn(app, @DifficultyBtnEasyPushed, true);
            app.DifficultyBtnEasy.Tooltip = {'Fixed Target'};
            app.DifficultyBtnEasy.Text = 'Easy';
            app.DifficultyTitleShadow = uilabel(app.DifficultyTab);
            app.DifficultyTitleShadow.Text = 'Choose Difficulty';
            app.DifficultyTitle = uilabel(app.DifficultyTab);
            app.DifficultyTitle.Text = 'Choose Difficulty';
            app.GameTab = uitab(app.TabGroup);
            app.GameTab.Title = 'Game';
            app.UIAxes = uiaxes(app.GameTab);
            app.UIAxes.XLim = [0 20];
            app.UIAxes.YLim = [0 10];
            app.UIAxes.XTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'; '12'; '14'; '16'; '18'; '20'};
            app.bg_game = uiimage(app.GameTab);
            app.bg_game.ImageSource = fullfile(pathToMLAPP, 'images', 'bg_game.jpg');
            app.QuitButton = uibutton(app.GameTab, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitBtnPushed, true);
            app.QuitButton.Text = 'Quit';
            app.ShootingAngle = uigauge(app.GameTab, 'ninetydegree');
            app.ShootingAngle.Limits = [0 90];
            app.ShootingAngle.ScaleDirection = 'counterclockwise';
            app.PlayButtonGame = uibutton(app.GameTab, 'push');
            app.PlayButtonGame.ButtonPushedFcn = createCallbackFcn(app, @PlayBtnGamePushed, true);
            app.PlayButtonGame.Text = 'Play';
            app.Slider = uislider(app.GameTab);
            app.Slider.Limits = [10 20];
            app.Slider.Value = 15;
            app.CurrentPosValue = uilabel(app.GameTab);
            app.CurrentPosValue.Text = '7th';
            app.CurrentPosLabel = uilabel(app.GameTab);
            app.CurrentPosLabel.Text = 'Current Position:';
            app.NextScoreValue = uilabel(app.GameTab);
            app.NextScoreValue.Text = '0';
            app.NextScoreLabel = uilabel(app.GameTab);
            app.NextScoreLabel.Text = 'Next High Score:';
            app.ScoreValueGame = uilabel(app.GameTab);
            app.ScoreValueGame.Text = '0';
            app.ScoreLabelGame = uilabel(app.GameTab);
            app.ScoreLabelGame.Text = 'Score:';
            app.LivesLabel = uilabel(app.GameTab);
            app.LivesLabel.Text = 'Lives:';
            app.img_lives5 = uiimage(app.GameTab);
            app.img_lives5.ImageSource = fullfile(pathToMLAPP, 'images', 'toppng.com-minions-png-transparent-image-minion-stuart-623x989.png');
            app.img_lives4 = uiimage(app.GameTab);
            app.img_lives4.ImageSource = fullfile(pathToMLAPP, 'images', 'toppng.com-minions-png-transparent-image-minion-stuart-623x989.png');
            app.img_lives3 = uiimage(app.GameTab);
            app.img_lives3.ImageSource = fullfile(pathToMLAPP, 'images', 'toppng.com-minions-png-transparent-image-minion-stuart-623x989.png');
            app.img_lives2 = uiimage(app.GameTab);
            app.img_lives2.ImageSource = fullfile(pathToMLAPP, 'images', 'toppng.com-minions-png-transparent-image-minion-stuart-623x989.png');
            app.img_lives1 = uiimage(app.GameTab);
            app.img_lives1.ImageSource = fullfile(pathToMLAPP, 'images', 'toppng.com-minions-png-transparent-image-minion-stuart-623x989.png');
            app.snowball = uiimage(app.GameTab);
            app.snowball.ImageSource = fullfile(pathToMLAPP, 'images', 'snowball1.PNG');
            app.vector = uiimage(app.GameTab);
            app.vector.ImageSource = fullfile(pathToMLAPP, 'images', 'bratt.png');
            app.WindLabel = uilabel(app.GameTab);
            app.WindLabel.Text = 'Wind: ';
            app.WindValue = uilabel(app.GameTab);
            app.WindValue.Text = '0';
            app.Instructions = uilabel(app.GameTab);
            app.Instructions.Text = 'Instructions';
            app.img_wind_direction = uiimage(app.GameTab);
            app.img_wind_direction.ImageSource = fullfile(pathToMLAPP, 'images', 'arrow.png');
            app.GameOverTab = uitab(app.TabGroup);
            app.GameOverTab.Title = 'GameOver';
            app.bg_gameover = uiimage(app.GameOverTab);
            app.bg_gameover.ImageSource = fullfile(pathToMLAPP, 'images', 'bg_gameover.jpg');
            app.DoneButtonScore = uibutton(app.GameOverTab, 'push');
            app.DoneButtonScore.ButtonPushedFcn = createCallbackFcn(app, @DoneBtnGameOverPushed, true);
            app.DoneButtonScore.Text = 'Done';
            app.NameField = uieditfield(app.GameOverTab, 'text');
            app.NameField.ValueChangedFcn = createCallbackFcn(app, @NameFieldValueChanged, true);
            app.NameField.Tooltip = {'Maximum three character'};
            app.NameField.Placeholder = 'Enter Name';
            app.ScoreValueScore = uilabel(app.GameOverTab);
            app.ScoreValueScore.Text = '0';
            app.ScoreLabelScore = uilabel(app.GameOverTab);
            app.ScoreLabelScore.Text = 'Score';
            app.NameLabel = uilabel(app.GameOverTab);
            app.NameLabel.Text = 'Name';
            app.HighScoresTab = uitab(app.TabGroup);
            app.HighScoresTab.Title = 'High Scores';
            app.bg_highscores = uiimage(app.HighScoresTab);
            app.bg_highscores.ImageSource = fullfile(pathToMLAPP, 'images', 'bg_highscores3dark2.png');
            app.BackButtonHighScore = uibutton(app.HighScoresTab, 'push');
            app.BackButtonHighScore.ButtonPushedFcn = createCallbackFcn(app, @BackBtnHighScorePushed, true);
            app.BackButtonHighScore.Text = 'Back';
            app.FourthPosImg = uiimage(app.HighScoresTab);
            app.FourthPosImg.ImageSource = fullfile(pathToMLAPP, 'images', 'cardboard1.png');
            app.FifthPosImg = uiimage(app.HighScoresTab);
            app.FifthPosImg.ImageSource = fullfile(pathToMLAPP, 'images', 'cardboard2.png');
            app.SixthPosImg = uiimage(app.HighScoresTab);
            app.SixthPosImg.ImageSource = fullfile(pathToMLAPP, 'images', 'cardboard1.png');
            app.podium = uiimage(app.HighScoresTab);
            app.podium.ImageSource = fullfile(pathToMLAPP, 'images', 'podium.png');
            app.FourthPos = uilabel(app.HighScoresTab);
            app.FourthPos.Text = '4th';
            app.kevin = uiimage(app.HighScoresTab);
            app.kevin.ImageSource = fullfile(pathToMLAPP, 'images', 'kevin.png');
            app.stuart = uiimage(app.HighScoresTab);
            app.stuart.ImageSource = fullfile(pathToMLAPP, 'images', 'stuart.png');
            app.king_bob = uiimage(app.HighScoresTab);
            app.king_bob.ImageSource = fullfile(pathToMLAPP, 'images', 'king_bob.png');
            app.SixthScore = uilabel(app.HighScoresTab);
            app.SixthScore.Text = '999';
            app.SixthName = uilabel(app.HighScoresTab);
            app.SixthName.Text = 'FFF';
            app.SixthPos = uilabel(app.HighScoresTab);
            app.SixthPos.Text = '6th';
            app.FifthScore = uilabel(app.HighScoresTab);
            app.FifthScore.Text = '999';
            app.FifthName = uilabel(app.HighScoresTab);
            app.FifthName.Text = 'EEE';
            app.FifthPos = uilabel(app.HighScoresTab);
            app.FifthPos.Text = '5th';
            app.FourthScore = uilabel(app.HighScoresTab);
            app.FourthScore.Text = '999';
            app.FourthName = uilabel(app.HighScoresTab);
            app.FourthName.Text = 'DDD';
            app.ThirdScore = uilabel(app.HighScoresTab);
            app.ThirdScore.Text = '999';
            app.ThirdName = uilabel(app.HighScoresTab);
            app.ThirdName.Text = 'CCC';
            app.SecondName = uilabel(app.HighScoresTab);
            app.SecondName.Text = 'BBB';
            app.SecondScore = uilabel(app.HighScoresTab);
            app.SecondScore.Text = '999';
            app.FirstScore = uilabel(app.HighScoresTab);
            app.FirstScore.Text = '999';
            app.FirstName = uilabel(app.HighScoresTab);
            app.FirstName.Text = 'AAA';
            app.ScoreBtnHard = uibutton(app.HighScoresTab, 'push');
            app.ScoreBtnHard.ButtonPushedFcn = createCallbackFcn(app, @ScoreBtnHardPushed, true);
            app.ScoreBtnHard.Text = 'Hard';
            app.ScoreBtnNormal = uibutton(app.HighScoresTab, 'push');
            app.ScoreBtnNormal.ButtonPushedFcn = createCallbackFcn(app, @ScoreBtnNormalPushed, true);
            app.ScoreBtnNormal.Text = 'Normal';
            app.ScoreBtnEasy = uibutton(app.HighScoresTab, 'push');
            app.ScoreBtnEasy.ButtonPushedFcn = createCallbackFcn(app, @ScoreBtnEasyPushed, true);
            app.ScoreBtnEasy.Text = 'Easy';
            app.HighScoreTitleShadow = uilabel(app.HighScoresTab);
            app.HighScoreTitleShadow.Text = 'High Scores';
            app.HighScoreTitle = uilabel(app.HighScoresTab);
            app.HighScoreTitle.Text = 'High Scores';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Assignment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end